classdef EMGProcessing32x1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ImportDataButton          matlab.ui.control.Button
        LeftBoundEditFieldLabel   matlab.ui.control.Label
        LeftBoundEditField        matlab.ui.control.NumericEditField
        RightBoundEditFieldLabel  matlab.ui.control.Label
        RightBoundEditField       matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        time = zeros(1) 
        emg = zeros(1)
        aux = zeros(1)
    end
    
    methods (Access = private)
        
        function results = plotAll(app)
            cla(app.UIAxes)
            hold(app.UIAxes, "on")
            % plot EMG and AUX values vs time
            for i = 1:32
                plot(app.time, app.emg(:,i)+5*(i), 'b-', 'Parent', app.UIAxes);
            end
            plot(app.time, 160*(app.aux-min(app.aux))/(max(app.aux)-min(app.aux)), 'k-', 'Parent', app.UIAxes);
            app.UIAxes.YTickLabel = {};
            
            % plot left and right bound
            plot(app.LeftBoundEditField.Value*ones(1,160), linspace(1,160,160), 'r-', 'Parent', app.UIAxes, 'LineWidth', 5)
            plot(app.RightBoundEditField.Value*ones(1,160), linspace(1,160,160), 'r-', 'Parent', app.UIAxes, 'LineWidth', 5)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.csv');
            opts = delimitedTextImportOptions("NumVariables", 34);
            opts.DataLines = [1, Inf];
            opts.Delimiter = ",";
            opts.VariableNames = ["VarName1", "VarName2", "VarName3", "VarName4", "VarName5", "VarName6", "VarName7", "VarName8", "VarName9", "VarName10", "VarName11", "VarName12", "VarName13", "VarName14", "VarName15", "VarName16", "VarName17", "VarName18", "VarName19", "VarName20", "VarName21", "VarName22", "VarName23", "VarName24", "VarName25", "VarName26", "VarName27", "VarName28", "VarName29", "VarName30", "VarName31", "VarName32", "VarName33", "VarName34"];
            opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            data = readtable(strcat(filepath,filename), opts);
            data = table2array(data);
            clear opts

            app.time = data(:,1);
            app.emg = data(:,2:33);
            app.aux = data(:,34);
            
            app.RightBoundEditField.Value = max(app.time);
            
            plotAll(app)
        end

        % Value changed function: LeftBoundEditField
        function LeftBoundEditFieldValueChanged(app, event)
            value = app.LeftBoundEditField.Value;
            if value > app.RightBoundEditField.Value;
                app.LeftBoundEditField.Value = app.RightBoundEditField.Value-1; 
            end
            if (value > max(app.time) || value < 0)
                app.LeftBoundEditField.Value = 0;
            end
            plotAll(app)
            
        end

        % Value changed function: RightBoundEditField
        function RightBoundEditFieldValueChanged(app, event)
            value = app.RightBoundEditField.Value;
            if value < app.LeftBoundEditField.Value;
                app.RightBoundEditField.Value = app.LeftBoundEditField.Value + 1;    
            end
            if (value > max(app.time) || value < 0)
                app.RightBoundEditField.Value = max(app.time);
            end      
            plotAll(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EMG Plots')
            xlabel(app.UIAxes, 'time (seconds)')
            ylabel(app.UIAxes, '')
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.LeftBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LeftBoundEditFieldLabel.Text = 'Left Bound';
            app.LeftBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftBoundEditField.ValueChangedFcn = createCallbackFcn(app, @LeftBoundEditFieldValueChanged, true);
            app.RightBoundEditFieldLabel = uilabel(app.UIFigure);
            app.RightBoundEditFieldLabel.Text = 'Right Bound';
            app.RightBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightBoundEditField.ValueChangedFcn = createCallbackFcn(app, @RightBoundEditFieldValueChanged, true);
            app.RightBoundEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMGProcessing32x1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end