classdef EMGProcessing_version0_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImportEMGDataButton         matlab.ui.control.Button
        ImportTorqueDataButton      matlab.ui.control.Button
        SaveResultsButton           matlab.ui.control.Button
        LeftBoundEditFieldLabel     matlab.ui.control.Label
        LeftBoundEditField          matlab.ui.control.EditField
        RightBoundEditFieldLabel    matlab.ui.control.Label
        RightBoundEditField         matlab.ui.control.EditField
        MidpointEditFieldLabel      matlab.ui.control.Label
        MidpointEditField           matlab.ui.control.EditField
        TimeWindowmsEditFieldLabel  matlab.ui.control.Label
        TimeWindowmsEditField       matlab.ui.control.EditField
        ClearFigureButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        humac = {} 
        trigno = {}
        trignoFilename = ''
    end
    
    methods (Access = private)


        function results = plotEMG(app)
            yyaxis(app.UIAxes, 'right')
            gain =1000;
            offset = 2;
            plot(app.trigno.Time, app.trigno.EMG1*gain+offset, 'b', 'Parent', app.UIAxes);
            hold (app.UIAxes, 'on');
            plot(app.trigno.Time, app.trigno.EMG2*gain+1, 'r' , 'Parent', app.UIAxes);
            app.UIAxes.XLim = [str2double(app.LeftBoundEditField.Value) str2double(app.RightBoundEditField.Value)];
            app.UIAxes.YLim = [0 5];
        end
        
        function results = plotTorque(app)
            yyaxis(app.UIAxes, 'left')
            plot(app.humac.Time, app.humac.Torque, 'k', 'Parent', app.UIAxes);         
            app.UIAxes.XLim = [str2double(app.LeftBoundEditField.Value) str2double(app.RightBoundEditField.Value)];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportEMGDataButton
        function ImportEMGDataButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.csv');            
            opts = delimitedTextImportOptions("NumVariables", 3);
            opts.DataLines = [2, Inf];
            opts.Delimiter = ",";
            opts.VariableNames = ["Time", "EMG1", "EMG2"];
            opts.VariableTypes = ["double", "double", "double"];
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            app.trigno = readtable(strcat(filepath,filename), opts);
            app.trignoFilename = filename(1:size(filename, 2)-11);
            clear opts;
            plotEMG(app);
        end

        % Button pushed function: ImportTorqueDataButton
        function ImportTorqueDataButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.csv');
            opts = delimitedTextImportOptions("NumVariables", 3);
            opts.DataLines = [2, Inf];
            opts.Delimiter = ",";
            opts.VariableNames = ["Time", "Torque", "Displacement"];
            opts.VariableTypes = ["double", "double", "double"];
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            app.humac = readtable(strcat(filepath,filename), opts);
            clear opts
            
            plotTorque(app);
        end

        % Value changed function: LeftBoundEditField
        function LeftBoundEditFieldValueChanged(app, event)
            app.UIAxes.XLim = [str2num(app.LeftBoundEditField.Value) str2num(app.RightBoundEditField.Value)];
        end

        % Value changed function: RightBoundEditField
        function RightBoundEditFieldValueChanged(app, event)
            app.UIAxes.XLim = [str2num(app.LeftBoundEditField.Value) str2num(app.RightBoundEditField.Value)];
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            midpoint = str2double(app.MidpointEditField.Value);
            timeWindow = str2double(app.TimeWindowmsEditField.Value)/2000;
            lowLimit = midpoint-timeWindow;
            highLimit = midpoint+timeWindow;
            
            humacMask = boolean((app.humac.Time>lowLimit).*(app.humac.Time<highLimit));
            trignoMask = boolean((app.trigno.Time>lowLimit).*(app.trigno.Time<highLimit));
            
            humacTime = app.humac.Time(humacMask)';
            torque = app.humac.Torque(humacMask)';
            trignoTime = app.trigno.Time(trignoMask)';
            EMG1 = app.trigno.EMG1(trignoMask)';
            EMG2 = app.trigno.EMG2(trignoMask)';
            
            %rms calculations
            rms_EMG1 = rms(EMG1);
            rms_EMG2 = rms(EMG2);
            
            %mdf calculations
            Fs = 1/(trignoTime(2)-trignoTime(1));
            mdf_EMG1 = medfreq(EMG1, Fs);
            mdf_EMG2 = medfreq(EMG2, Fs);
            
            %avg torque calculations
            ave_torque = sum(torque)/sum(humacMask);
            
            %split trigno filename into individual names
            headings = strsplit(app.trignoFilename, '_');
            
            
            %write csv file
            varNames = {'ParticipantNumber', 'Side', 'pctMVC', 'TrialNumber', 'DateAcquired', 'AveTorque', 'Midpoint', 'TimeWindow', 'RMS_EMG1', 'RMS_EMG2', 'MDF_EMG1', 'MDF_EMG2'};
            if ~exist('results.csv')
                data = cell2table(cell(0,12));
                data.Properties.VariableNames = varNames;
                writetable(data, 'results.csv');         
            end

            data1 = readtable('results.csv');
            
            data2 = cell2table([headings, ave_torque, midpoint, app.TimeWindowmsEditField.Value, rms_EMG1, rms_EMG2, mdf_EMG1, mdf_EMG2]);
            data2.Properties.VariableNames = varNames;
            data2.pctMVC = str2num(data2.pctMVC{1});
            data2.TrialNumber = str2num(data2.TrialNumber{1});
            data2.TimeWindow = str2num(data2.TimeWindow{1});
            
            newdata = [data1; data2];
            writetable(newdata, 'results.csv');
            
%             csvwrite('filename.csv', [trignoTime; EMG1; EMG2])
%             temp = csvread('filename.csv')
%             csvwrite('filename.csv', temp')
        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed(app, event)
            cla(app.UIAxes, 'reset')
            yyaxis(app.UIAxes, 'right')
            app.UIAxes.XLim = [str2double(app.LeftBoundEditField.Value) str2double(app.RightBoundEditField.Value)];
            app.UIAxes.YLim = [0 5];
            
            yyaxis(app.UIAxes, 'left')       
            app.UIAxes.XLim = [str2double(app.LeftBoundEditField.Value) str2double(app.RightBoundEditField.Value)];            
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImportEMGDataButton = uibutton(app.UIFigure, 'push');
            app.ImportEMGDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEMGDataButtonPushed, true);
            app.ImportEMGDataButton.Text = 'Import EMG Data';
            app.ImportTorqueDataButton = uibutton(app.UIFigure, 'push');
            app.ImportTorqueDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTorqueDataButtonPushed, true);
            app.ImportTorqueDataButton.Text = 'Import Torque Data';
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.LeftBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LeftBoundEditFieldLabel.Text = 'Left Bound';
            app.LeftBoundEditField = uieditfield(app.UIFigure, 'text');
            app.LeftBoundEditField.ValueChangedFcn = createCallbackFcn(app, @LeftBoundEditFieldValueChanged, true);
            app.LeftBoundEditField.Value = '0';
            app.RightBoundEditFieldLabel = uilabel(app.UIFigure);
            app.RightBoundEditFieldLabel.Text = 'Right Bound';
            app.RightBoundEditField = uieditfield(app.UIFigure, 'text');
            app.RightBoundEditField.ValueChangedFcn = createCallbackFcn(app, @RightBoundEditFieldValueChanged, true);
            app.RightBoundEditField.Value = '100';
            app.MidpointEditFieldLabel = uilabel(app.UIFigure);
            app.MidpointEditFieldLabel.Text = 'Midpoint';
            app.MidpointEditField = uieditfield(app.UIFigure, 'text');
            app.MidpointEditField.Value = '10';
            app.TimeWindowmsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeWindowmsEditFieldLabel.Text = 'Time Window (ms)';
            app.TimeWindowmsEditField = uieditfield(app.UIFigure, 'text');
            app.TimeWindowmsEditField.Value = '250';
            app.ClearFigureButton = uibutton(app.UIFigure, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMGProcessing_version0_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end